classdef Proto_Grid_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ExecuteLabel          matlab.ui.control.Label
        InputLabel            matlab.ui.control.Label
        InputEditField        matlab.ui.control.EditField
        InputEditFieldLabel   matlab.ui.control.Label
        ExecuteButton         matlab.ui.control.Button
        CommandsListBox       matlab.ui.control.ListBox
        CommandsListBoxLabel  matlab.ui.control.Label
        UnitDropDown          matlab.ui.control.DropDown
        UnitDropDownLabel     matlab.ui.control.Label
        StopButton            matlab.ui.control.Button
        StartButton           matlab.ui.control.Button
        StatusTextArea        matlab.ui.control.TextArea
        StatusTable           matlab.ui.control.Table
        StatusGraph           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        grid % grid object
        FID % log txt file identifier
        runFlag % keep sending getAll or not
        xlsSheetHeader % header line for reading grid spreadsheet
        inputCount % num of user input received
        currCmd % the current cmd obj
        inputVals % user input from InputEditFiled

    end
    
    methods (Access = private)
        % initialize the unit drop down menu
        function initUnitDropDown(app)
            % get all available unit IDs
            IDList(1) = "grid";
            for i = 1:app.grid.unitCount
                IDList(i + 1) = app.grid.unitList{i}.ID;
            end
            app.UnitDropDown.Items = IDList;
        end
        
        function initCommandsListBox(app)
            % populate cmd list with grid cmds
            cmdMenu = app.grid.cmdMenu;
            app.CommandsListBox.Items = keys(cmdMenu);
            app.CommandsListBox.Tooltip = cmdMenu(app.CommandsListBox.Value);
        end
        
        function updateStatusTextArea(app)
            % read and display log file
            app.StatusTextArea.Value = fileread("log.txt");
            scroll(app.StatusTextArea, 'bottom');
        end
        
        function turnOffInputEditField(app)
            app.InputEditField.Enable = "off";
            app.InputEditField.Editable = "off";
        end

        function turnOnInputEditField(app)
            app.InputEditField.Enable = "on";
            app.InputEditField.Editable = "on";
        end
        
        function initInputEditField(app)
            app.InputEditField.InputType = "digit";
        end
        
        function initStatusGraph(app)
            app.StatusGraph.YLim = [-850 1000];
        end
        
        function hideAllButtons(app)
            % hide all interactive app components during app init
            app.StartButton.Visible = "off";
            app.StopButton.Visible = "off";
            app.ExecuteButton.Visible = "off";
            app.CommandsListBox.Visible = "off";
            app.UnitDropDown.Visible = "off";
            app.InputEditField.Visible = "off";
        end

        function showAllButtons(app)
            % display all interactive app components after app init
            app.StartButton.Visible = "on";
            app.StopButton.Visible = "on";
            app.ExecuteButton.Visible = "on";
            app.CommandsListBox.Visible = "on";
            app.UnitDropDown.Visible = "on";
            app.InputEditField.Visible = "on";
        end
        
        function adjustYaxisLimit(app, axis, data)
            maxDataVal = max(data);
            minDataVal = min(data);
            if maxDataVal <= 300
                maxLim = 400;
            elseif maxDataVal <= 500
                maxLim = 600;
            elseif maxDataVal <= 700
                maxLim = 800;
            else
                maxLim = 1000;
            end

            if minDataVal >= 0
                minLim = 0;
            elseif minDataVal >= -260
                minLim = -300;
            else
                minLim = -850;
            end
            axis.YLim = [minLim maxLim];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.StatusTextArea.Value = "Initializing APP...";
            app.hideAllButtons();
            delete("log.txt");
            app.FID = fopen("log.txt", "w+"); % create new log file
            delete("statusData.xlsx"); % delete prev excel file
            % initialize grid
            app.grid = gridObj();
            app.grid.setFID(app.FID);
            app.grid.reportGridStatus();
            % initialize private properties
            app.runFlag = 0;
            app.xlsSheetHeader = 0;
            app.inputCount = 0;
            app.inputVals = [];
            % initialize ui components
            app.initUnitDropDown();
            app.initCommandsListBox();
            app.initInputEditField();
            app.initStatusGraph();
            app.turnOffInputEditField();
            app.updateStatusTextArea();
            app.showAllButtons();
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.runFlag = 1;
            str = "* Plot started" + newline;
            fprintf(app.FID, str);
            app.updateStatusTextArea();
            % generator track on
            if ismember("generator", app.grid.unitIDs) == 1
                unit = app.grid.getUnit("generator");
                app.grid.writeSP(unit.SP, "trackOn");
            end

            while app.runFlag == 1 % keep updating graph while runFlag is on
                app.grid.updateStatusAll();
                
                app.StatusTable.Data = app.grid.gridStatus;
               
                for i = 1:app.grid.unitCount % for each unit
                    % get their last 50 status data from excel
                    graphYData = app.grid.unitList{i}.status50;
                    graphXData = app.grid.unitList{i}.time50;
                    maxVals(i) = max(graphYData);
                    minVals(i) = min(graphYData);
                    
                    % plot the status data
                    plot(app.StatusGraph, graphXData, graphYData, "LineWidth", 2);
                  
                    % plot all units in the same graph
                    if i == app.grid.unitCount
                        hold(app.StatusGraph, "off");
                    else
                        hold(app.StatusGraph, "on");
                    end
                end % for
                app.adjustYaxisLimit(app.StatusGraph, [maxVals minVals]);
                legend(app.StatusGraph, app.grid.unitIDs);
                app.grid.setGridLoad();
                
                pause(0.5) % control graphing frequency
                
            end % while
        end

        % Value changed function: CommandsListBox
        function CommandsListBoxValueChanged(app, event)
            % update tooltip of list box when user selects diff item
            value = app.CommandsListBox.Value;
            ID = app.UnitDropDown.Value;
            if ID == "grid"
                app.CommandsListBox.Tooltip = app.grid.cmdMenu(value);
            else
                unit = app.grid.getUnit(ID);
                app.CommandsListBox.Tooltip = unit.cmds(value).description;
            end
        end

        % Value changed function: UnitDropDown
        function UnitDropDownValueChanged(app, event)
            % update listbox tooltip when drop down value is changed
            value = app.UnitDropDown.Value;
            if value == "grid"
                app.CommandsListBox.Items = keys(app.grid.cmdMenu);
                app.CommandsListBox.Tooltip = app.grid.cmdMenu(app.CommandsListBox.Value);
            else
                unit = app.grid.getUnit(value);
                app.CommandsListBox.Items = keys(unit.cmds);
                app.CommandsListBox.Tooltip = unit.cmdMenu(app.CommandsListBox.Value);
            end
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            % execute the selected cmd
            
            ID = app.UnitDropDown.Value;
            cmdName = app.CommandsListBox.Value;
            str = "* " + ID + ": " + cmdName + newline;
            fprintf(app.FID, str);
            if ID == "grid" % grid cmd
                app.grid.sendAll(cmdName);
                app.updateStatusTextArea();
            else % unit cmd
                unit = app.grid.getUnit(ID);
                if unit.cmds(cmdName).out ~= 0 % if cmd needs user input
                    % app.updateStatusTextArea();
                    % print prompt
                    app.currCmd = unit.cmds(cmdName);
                    if app.currCmd.prompts == "unknown: "
                        str = " - input: ";
                    else 
                        str = "  - " + app.currCmd.prompts(1);
                    end
                    fprintf(app.FID, str);
                    app.updateStatusTextArea();
                    % enable input edit field
                    app.turnOnInputEditField();
                    focus(app.InputEditField);
                elseif unit.cmds(cmdName).in ~= 0 % if cmd takes input from Arduino
                    if unit.SP.BytesAvailable ~= 0 % reject if unit serial port is busy
                        app.ExecuteLabel.Text = "Serial port busy, please try again.";
                        return;
                    else
                        app.ExecuteLabel.Text = "";
                    end
                    % send out cmd
                    retVal = unit.call(cmdName);
                    % write return value to log file
                    numOfInput = unit.cmds(cmdName).in;
                    for i = 1:numOfInput
                        str = "  > " + retVal(i) + newline;
                        fprintf(app.FID, str);
                    end
                    app.updateStatusTextArea();
                else % cmd w/o input and output
                    unit.call(cmdName);
                    app.updateStatusTextArea();
                end % if, execute diff types of cmds
            end % if, grid or unit cmds
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            answer = questdlg("Would you like to save the simulation data?", ...
                     "Data Storage Request", ...
                     "Yes", "No", "Cancel", "Cancel");
            
            if answer == "Cancel"
                return;
            end 

            if answer == "Yes"
                % create "History" folder
                if ~exist("History", "dir")
                    mkdir History
                end
                % create a folder to store historical data
                % use datatime as folder name
                newFolderName = string(datetime);
                newFolderName = regexprep(newFolderName, ' ', "-");
                newFolderName = regexprep(newFolderName, ':', "-");
                newFolderName = regexprep(newFolderName, '-', "_");
                path = fullfile(pwd, "History");
                mkdir(path, newFolderName);
                path = fullfile(path, newFolderName);
                if exist("log.txt", "file")
                    copyfile("log.txt", path, "f");
                end
                if exist("statusData.xlsx", "file")
                    copyfile("statusData.xlsx", path, "f");
                end

            end
            fclose("all"); % close all open files
            
            app.grid.gridOff(); % turns all units off
            delete(app)
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.runFlag = 0;
            fprintf(app.FID, "* Plot paused\n");
            app.updateStatusTextArea();
        end

        % Value changed function: InputEditField
        function InputEditFieldValueChanged2(app, event)
            value = app.InputEditField.Value;
            app.InputEditField.Value = ''; % clear edit field

            unitID = app.UnitDropDown.Value;
            unit = app.grid.getUnit(unitID);
            cmdName = string(app.currCmd.name);
            % check if user input is valid or not
            validFlag = unit.checkValid(app.currCmd, str2double(value));
            if validFlag == 1 % is valid
                app.inputCount = app.inputCount + 1;
                app.InputLabel.Text = "";
            else % not valid
                app.InputLabel.Text = "Invalid Input!";
                return;
            end

            % display valid input in statusTextArea
            app.inputVals(app.inputCount) = str2double(value);
            str = string(value) + newline;
            
            % send cmd if is last input
            if app.inputCount ~= app.currCmd.out % not last input
                % display next prompt
                if app.currCmd.prompts == "unknown: "
                        str = str + " - input: ";
                else
                    str = str + "  - " + app.currCmd.prompts(app.inputCount + 1);
                end
            else % is last input
                app.turnOffInputEditField(); % disable edit field
                unit.call(cmdName, app.inputVals);
                % reset input val and count property for next cmd
                app.inputVals = [];
                app.inputCount = 0;
            end % if, last input

            fprintf(app.FID, str);
            app.updateStatusTextArea();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StatusGraph = uiaxes(app.UIFigure);
            title(app.StatusGraph, 'Power Generated vs Time')
            xlabel(app.StatusGraph, 'Time (hh:mm:ss)')
            ylabel(app.StatusGraph, 'Power (kW)')
            zlabel(app.StatusGraph, 'Z')
            app.StatusGraph.XLim = [0 50];
            app.StatusGraph.XLimitMethod = 'tight';
            app.StatusGraph.YLimitMethod = 'tight';
            app.StatusGraph.XTick = [0 10 20 30 40 50];
            app.StatusTable = uitable(app.UIFigure);
            app.StatusTable.ColumnName = {'Time'; 'Unit'; 'Max KW'; 'Curr Load'; 'Allocated Load'; 'Delta Load'; 'Carbon'; 'Renewable'; 'Voltage'; 'Pos/Temp'};
            app.StatusTable.RowName = {};
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.StatusTextArea.Tag = 'Status';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.UnitDropDownLabel = uilabel(app.UIFigure);
            app.UnitDropDownLabel.Text = 'Unit';
            app.UnitDropDown = uidropdown(app.UIFigure);
            app.UnitDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitDropDownValueChanged, true);
            app.CommandsListBoxLabel = uilabel(app.UIFigure);
            app.CommandsListBoxLabel.Text = 'Commands';
            app.CommandsListBox = uilistbox(app.UIFigure);
            app.CommandsListBox.ValueChangedFcn = createCallbackFcn(app, @CommandsListBoxValueChanged, true);
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.InputEditFieldLabel = uilabel(app.UIFigure);
            app.InputEditFieldLabel.Text = 'Input';
            app.InputEditField = uieditfield(app.UIFigure, 'text');
            app.InputEditField.InputType = 'digits';
            app.InputEditField.ValueChangedFcn = createCallbackFcn(app, @InputEditFieldValueChanged2, true);
            app.InputLabel = uilabel(app.UIFigure);
            app.InputLabel.Text = '';
            app.ExecuteLabel = uilabel(app.UIFigure);
            app.ExecuteLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Proto_Grid_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end