classdef SolarIVApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        PlotKWButton          matlab.ui.control.StateButton
        ExecuteLabel          matlab.ui.control.Label
        PlotIVButton          matlab.ui.control.Button
        StatusTextArea        matlab.ui.control.TextArea
        ExecuteButton         matlab.ui.control.Button
        InputEditField        matlab.ui.control.EditField
        InputEditFieldLabel   matlab.ui.control.Label
        CommandsListBox       matlab.ui.control.ListBox
        CommandsListBoxLabel  matlab.ui.control.Label
        IVAxes                matlab.ui.control.UIAxes
        KWAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        solar = solarObj; % solar object
        textAreaVal = "";
        currCmd ;
        KWValArr;
        timeArr = datetime;
    end
    
    methods (Access = private)
        
        function displayText(app, message)
            app.textAreaVal = app.textAreaVal + message;
            app.StatusTextArea.Value = app.textAreaVal;
            scroll(app.StatusTextArea, 'bottom')
        end
        
        function hideAllButtons(app)
            app.CommandsListBox.Visible = "off";
            app.CommandsListBoxLabel.Visible = "off";
            app.InputEditField.Visible = "off";
            app.InputEditFieldLabel.Visible = "off";
            app.PlotKWButton.Visible = "off";
            app.PlotIVButton.Visible = "off";
            app.ExecuteButton.Visible = "off";
        end
        
        function showAllButtons(app)
            app.CommandsListBox.Visible = "on";
            app.CommandsListBoxLabel.Visible = "on";
            app.InputEditField.Visible = "on";
            app.InputEditFieldLabel.Visible = "on";
            app.PlotKWButton.Visible = "on";
            app.PlotIVButton.Visible = "on";
            app.ExecuteButton.Visible = "on";
        end

        function turnOffInputEditField(app)
            app.InputEditField.Enable = "off";
            app.InputEditField.Editable = "off";
        end

        function turnOnInputEditField(app)
            app.InputEditField.Enable = "on";
            app.InputEditField.Editable = "on";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % initialize textarea
            app.displayText("* Initializing APP..." + newline);
            app.hideAllButtons();
            % register solar module
            moduleList = regReviGridObjs;
            moduleListSize = size(moduleList);
            foundSolar = false;
            for i = 1:moduleListSize(2)
                currModule = moduleList{i};
                if currModule.ID == "solartracker"
                    foundSolar = true;
                    app.solar = currModule;
                end
            end

            if ~foundSolar
                app.displayText("* Can't find Solar Panel Module." + newline);
                return;
            end
            pause(5);
            app.displayText("* Found Solar Panel Module" + newline);
            app.CommandsListBox.Items = keys(app.solar.cmds);
            app.CommandsListBox.Tooltip = app.solar.cmdMenu(app.CommandsListBox.Value);
            app.showAllButtons();
            app.turnOffInputEditField();
 
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            cmdName = app.CommandsListBox.Value;
            app.displayText("* " + cmdName + newline)
            % send out cmd

            if app.solar.cmds(cmdName).out ~= 0 % if cmd needs user input
                % app.updateStatusTextArea();
                % print prompt
                app.currCmd = app.solar.cmds(cmdName);
                if app.currCmd.prompts == "unknown: "
                    str = " - input: ";
                else 
                    str = "  - " + app.currCmd.prompts(1);
                end
                app.displayText(str);
                % enable input edit field
                app.turnOnInputEditField();
            elseif app.solar.cmds(cmdName).in ~= 0 % if cmd takes input from Arduino
                if app.solar.SP.BytesAvailable ~= 0 % reject if unit serial port is busy
                    disp(app.solar.SP.BytesAvailable)
                    app.ExecuteLabel.Text = "Serial port busy, please try again.";
                    disp(readline(app.solar.SP))
                    return;
                else
                    app.ExecuteLabel.Text = "";
                end
                % send out cmd
                retVal = app.solar.call(cmdName);
       
                % write return value to log file
                numOfInput = app.solar.cmds(cmdName).in;
                for i = 1:numOfInput
                    str = "  > " + retVal(i) + newline;
                    app.displayText(str);
                end
            else % cmd w/o input and output
                app.solar.call(cmdName);
            end % if, execute diff types of cmds
        end

        % Value changed function: InputEditField
        function InputEditFieldValueChanged(app, event)
            value = app.InputEditField.Value;
            app.InputEditField.Value = ''; % clear edit field

            cmdName = string(app.currCmd.name);
            % check if user input is valid or not
            validFlag = app.solar.checkValid(app.currCmd, str2double(value));
            if validFlag == 0 % is valid
                app.ExecuteLabel.Text = "Invalid Input!";
                return;
            end

            % display valid input in statusTextArea
            app.displayText(str2double(value));
            app.displayText(newline);

            
            app.turnOffInputEditField(); % disable edit field
            app.solar.call(cmdName, str2double(value));
            pause(0.01);
        end

        % Value changed function: PlotKWButton
        function PlotKWButtonValueChanged(app, event)

            while(app.PlotKWButton.Value == 1)
                currKWVal = app.solar.call("getVal");
                currKWVal = str2double(currKWVal);
                KWValArrSize = size(app.KWValArr);
                KWValArrSize = KWValArrSize(2);
                currTime = datetime("now");
                if KWValArrSize < 300
                    app.KWValArr(KWValArrSize + 1) = currKWVal;
                    app.timeArr(KWValArrSize + 1) = currTime;
                else
                    app.KWValArr = circshift(app.KWValArr, -1);
                    app.KWValArr(300) = currKWVal;
                    app.timeArr = circshift(app.timeArr, -1);
                    app.timeArr(300) = currTime;
                end
                if max(app.KWValArr) >= 300
                    app.KWAxes.YLim = [0, max(app.KWValArr) + 100];
                else
                    app.KWAxes.YLim = [0, 300];
                end
           
                plot(app.KWAxes, app.timeArr, app.KWValArr);
                %datetick(app.KWAxes,'x','mm:ss');
            end
        end

        % Button pushed function: PlotIVButton
        function PlotIVButtonPushed(app, event)
            if app.PlotKWButton.Value == 1
                app.displayText("* KW plot paused");
            end
            app.PlotKWButton.Value == 0;
            app.PlotKWButton.Enable = "off";
            app.ExecuteButton.Enable = "off";
            app.PlotIVButton.Enable = "off";
            app.solar.writeSP("runIVScan");
            returnedVal = readline(app.solar.SP); % read table header
            app.displayText("* Ploting IV Curve..." + newline);
            for i = 1:255
                returnedVal(i) = readline(app.solar.SP);
                splitStr = strsplit(returnedVal(i), ' , ');
                resistance(i) = str2double(splitStr(1));
                voltage(i) = str2double(splitStr(2));
                current(i) = str2double(splitStr(3));
                power(i) = str2double(splitStr(4));
                
                
                plot(app.IVAxes, voltage, current, 'DisplayName', "Current(mA)");
                hold(app.IVAxes, 'on');
                plot(app.IVAxes, voltage, power, 'DisplayName', "Power(mW)");
                hold(app.IVAxes, 'on');
                plot(app.IVAxes, voltage(i), current(i),'o','markerSize',10,'DisplayName', "Current Current");
                hold(app.IVAxes, 'on');
                plot(app.IVAxes, voltage(i), power(i),'o','markerSize',10, 'DisplayName', "Current Power");
                hold(app.IVAxes, 'off');
                legend(app.IVAxes);

                % adjust XLim
                if max(voltage) > 1
                    maxLim = max(voltage) + 0.25;
                    app.IVAxes.XLim = [0 maxLim];
                end
    
                % adjust YLim
                if max([current power]) > 0.2
                    maxLim = max([current power]) + 0.05;
                    app.IVAxes.YLim = [0 maxLim];
                end
                
            end
            plot(app.IVAxes, voltage, current, 'DisplayName', "Current(mA)");
            hold(app.IVAxes, 'on');
            plot(app.IVAxes, voltage, power, 'DisplayName', "Power(mW)");
            hold(app.IVAxes, 'off');
            legend(app.IVAxes);
            returnedVal = readline(app.solar.SP); % read eod 
            returnedVal = readline(app.solar.SP); % read 255 
            
            % adjust XLim
            if max(voltage) > 1
                maxLim = max(voltage) + 0.25;
                app.IVAxes.XLim = [0 maxLim];
            end

            % adjust YLim
            if max([current power]) > 0.2
                maxLim = max([current power]) + 0.05;
                app.IVAxes.YLim = [0 maxLim];
            end


            app.PlotKWButton.Enable = "on";
            app.ExecuteButton.Enable = "on";
            app.PlotIVButton.Enable = "on";
            app.displayText("* IV Curve is done" + newline);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.solar.call("off");
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.KWAxes = uiaxes(app.UIFigure);
            title(app.KWAxes, 'kW Generated vs Time')
            xlabel(app.KWAxes, 'Time (hh:mm:ss)')
            ylabel(app.KWAxes, 'Power (kW) ')
            zlabel(app.KWAxes, 'Z')
            app.KWAxes.XLim = [0 100];
            app.KWAxes.YLim = [0 300];
            app.KWAxes.XLimitMethod = 'tight';
            app.KWAxes.YLimitMethod = 'tight';
            app.IVAxes = uiaxes(app.UIFigure);
            title(app.IVAxes, 'Current and Power vs Voltage')
            xlabel(app.IVAxes, 'Voltage (V)')
            ylabel(app.IVAxes, 'Current (mA) /Power (mW)')
            zlabel(app.IVAxes, 'Z')
            app.IVAxes.XLim = [0 2.5];
            app.IVAxes.YLim = [0 0.2];
            app.CommandsListBoxLabel = uilabel(app.UIFigure);
            app.CommandsListBoxLabel.Text = 'Commands';
            app.CommandsListBox = uilistbox(app.UIFigure);
            app.InputEditFieldLabel = uilabel(app.UIFigure);
            app.InputEditFieldLabel.Text = 'Input';
            app.InputEditField = uieditfield(app.UIFigure, 'text');
            app.InputEditField.ValueChangedFcn = createCallbackFcn(app, @InputEditFieldValueChanged, true);
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.PlotIVButton = uibutton(app.UIFigure, 'push');
            app.PlotIVButton.ButtonPushedFcn = createCallbackFcn(app, @PlotIVButtonPushed, true);
            app.PlotIVButton.Text = 'Plot IV';
            app.ExecuteLabel = uilabel(app.UIFigure);
            app.ExecuteLabel.Text = '';
            app.PlotKWButton = uibutton(app.UIFigure, 'state');
            app.PlotKWButton.ValueChangedFcn = createCallbackFcn(app, @PlotKWButtonValueChanged, true);
            app.PlotKWButton.Text = 'Plot KW';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SolarIVApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end